classdef MApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EBSDDataAnalysisPanel          matlab.ui.container.Panel
        EulerImage                     matlab.ui.control.Image
        MapImage                       matlab.ui.control.Image
        EulerCoordinatesDropDown       matlab.ui.control.DropDown
        EulerCoordinatesDropDownLabel  matlab.ui.control.Label
        MapCoordinatesDropDown         matlab.ui.control.DropDown
        MapCoordinatesDropDownLabel    matlab.ui.control.Label
        TypeofPlotDropDown             matlab.ui.control.DropDown
        TypeofPlotDropDownLabel        matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        CurrentData                    matlab.ui.control.EditField
        Panel                          matlab.ui.container.Panel
        DataTable                      matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        ImportEBSDDataButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        ImportedData % EBSD Data imported by import_data
        Color 
        DataToPlot
        OrientationPanel
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: ImportEBSDDataButton
        function ImportEBSDDataButtonPushed(app, event)
            app.DataTable.Data = [];
            removeStyle(app.DataTable);
            app.TypeofPlotDropDown.Items = {};

            % create the check-boxes of column 'Enabled'
            app.DataTable.ColumnEditable = [true false false false false false false false false];
            app.DataTable.ColumnFormat = {'logical',[],[],[],[],[],[],[],[]};

            % create a style
            s = uistyle('HorizontalAlignment','left');           
            addStyle(app.DataTable,s)

            % Import EBSD Data using the import_data function 
            [data, fileName] = import_data();

            % case for termination of import window
            if isempty(data)
                return
            end

            app.ImportedData = data; 
            app.DataToPlot = app.ImportedData;
            % Displaying the Name of the imported File
            app.CurrentData.Value = fileName;

            % fill UIAxes with the plotted data, only gets update once here
            % after pressing the 'Import EBSD' button
            % !!! Figure created by plot() cannot be closed
            cla(app.UIAxes);
            axes(app.UIAxes);
            plot(app.DataToPlot,'parent',app.UIAxes);


            PhaseId_array = unique(app.ImportedData.phaseId);
            % new way of filling out DataTable
            for i = 1:1:length(unique(app.ImportedData.phaseId))


                PhaseId = PhaseId_array(i);
                cs = app.ImportedData.CSList{i};

                if strcmp(cs,'notIndexed')
                    Mineral = 'NotIndexed';
                    Pixels = nnz(app.ImportedData.phaseId == PhaseId);
                    Symmetry = 'None';
                    a = 0;
                    b = 0;
                    c = 0;
                else
                    Mineral= app.ImportedData(app.ImportedData.phaseId == PhaseId).CS.mineral;
                    Pixels = nnz(app.ImportedData.phaseId == PhaseId);
                    Symmetry = app.ImportedData(app.ImportedData.phaseId == PhaseId).CS.pointGroup;
                    a = norm(app.ImportedData(app.ImportedData.phaseId == PhaseId).CS.aAxis);
                    b = norm(app.ImportedData(app.ImportedData.phaseId == PhaseId).CS.bAxis);
                    c = norm(app.ImportedData(app.ImportedData.phaseId == PhaseId).CS.cAxis);

                    app.Color{i} = app.ImportedData(app.ImportedData.phaseId == PhaseId).CS.color;

                    s.BackgroundColor = [app.Color{i}];
                    addStyle(app.DataTable,s,"cell",[i,9]);
                end
                
                % create new entry for 
                newEntry = {true, PhaseId, Mineral, Pixels, Symmetry, a, b, c,''};
                app.DataTable.Data = [app.DataTable.Data;newEntry];

            end

            % get the properties of the file and put them into the "Type of
            % Plot" DropDown 
            % create a Cell Array OtherTypes for more types of plots 
            OtherTypes = {'EBSD';
                          'IPF' };
            app.TypeofPlotDropDown.Items = [OtherTypes;fieldnames(app.ImportedData.prop)];
        end

        % Cell edit callback: DataTable
        function DataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % check for invalid selection or selection of phase
            % 'NotIndexed'
            if isempty(indices)
                return
            elseif strcmp(app.DataTable.Data(indices(1),3),'NotIndexed')
                return
            end

            rows = indices(1);
            cols = indices(2);

            % create a mask to disable/enable corresponding phases of
            % logical values in DataTable (checkboxes)
            mask = logical(cell2mat(app.DataTable.Data(:,1)));

            % filter for enabled phases 
            app.DataToPlot = app.ImportedData(ismember(app.ImportedData.phaseId, find(mask)));

            % update plot in UIAxes 
            cla(app.UIAxes);
            axes(app.UIAxes);
            plot(app.DataToPlot,'parent',app.UIAxes);
            
            
        end

        % Callback function
        function DataTableSelectionChanged(app, event)

        end

        % Cell selection callback: DataTable
        function DataTableCellSelection(app, event)
            indices = event.Indices;
            
            % check if indices has a invalid value or if 'NotIndexed' color
            % cell has been selected, if so, return to prohibit change 
            if isempty(indices)
                return
            elseif strcmp(app.DataTable.Data(indices(1),3),'NotIndexed')
                return
            end

            rows = indices(1);
            cols = indices(2);

            % check for selection of cells in column 9 (Color cells)
            if cols == 9

                NewColor = uisetcolor(app.Color{rows});

                % case for termination of uisetcolor window
                if NewColor == 0 
                    return
                end

                app.ImportedData.CSList{rows}.color = NewColor;
                app.Color{rows} = NewColor;
                % change color of selected cell
                s = uistyle;
                s.BackgroundColor = [app.ImportedData.CSList{rows}.color];
                addStyle(app.DataTable,s,"cell",[rows,cols]);
            end

            % update plot in UIAxes (still opens plot windows of plot()
            % function) 
            
            cla(app.UIAxes);
            axes(app.UIAxes);
            plot(app.DataToPlot,'parent',app.UIAxes);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % has no function yet
            if isempty(app.ImportedData)
                return
            end

            app.ExportButton.Text = 'Exported !';
        end

        % Value changed function: TypeofPlotDropDown
        function TypeofPlotDropDownValueChanged(app, event)
            value = app.TypeofPlotDropDown.Value;
            if strcmp(value,'EBSD')
                cla(app.UIAxes);
                axes(app.UIAxes);
                plot(app.DataToPlot,'parent',app.UIAxes);
            elseif strcmp(value,'IPF')
                % DataToPlot has to contain only a one phase (one phase is
                % enabled) or the regular plot(ebsd) will be displayed
                colors = ipfColorKey(app.DataToPlot).orientation2color(app.DataToPlot.orientations);
                cla(app.UIAxes);
                axes(app.UIAxes);
                plot(app.DataToPlot,colors,'parent',app.UIAxes);
            else
                cla(app.UIAxes);
                axes(app.UIAxes);
                plot(app.ImportedData,app.ImportedData.(value),'parent',app.UIAxes);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EBSDDataAnalysisPanel = uipanel(app.UIFigure);
            app.EBSDDataAnalysisPanel.Title = 'EBSD Data Analysis';
            app.ImportEBSDDataButton = uibutton(app.EBSDDataAnalysisPanel, 'push');
            app.ImportEBSDDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEBSDDataButtonPushed, true);
            app.ImportEBSDDataButton.Text = 'Import EBSD-Data';
            app.Panel = uipanel(app.EBSDDataAnalysisPanel);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataTable = uitable(app.Panel);
            app.DataTable.ColumnName = {'Plot'; 'Phase'; 'Mineral'; 'Pixel'; 'Symmetry'; 'a'; 'b'; 'c'; 'Color'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnEditable = [true false false true false true true true true];
            app.DataTable.CellEditCallback = createCallbackFcn(app, @DataTableCellEdit, true);
            app.DataTable.CellSelectionCallback = createCallbackFcn(app, @DataTableCellSelection, true);
            app.CurrentData = uieditfield(app.EBSDDataAnalysisPanel, 'text');
            app.CurrentData.Editable = 'off';
            app.Label = uilabel(app.EBSDDataAnalysisPanel);
            app.ExportButton = uibutton(app.EBSDDataAnalysisPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TypeofPlotDropDownLabel = uilabel(app.EBSDDataAnalysisPanel);
            app.TypeofPlotDropDownLabel.Text = 'Type of Plot';
            app.TypeofPlotDropDown = uidropdown(app.EBSDDataAnalysisPanel);
            app.TypeofPlotDropDown.Items = {};
            app.TypeofPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofPlotDropDownValueChanged, true);
            app.TypeofPlotDropDown.Value = {};
            app.MapCoordinatesDropDownLabel = uilabel(app.EBSDDataAnalysisPanel);
            app.MapCoordinatesDropDownLabel.Text = 'Map Coordinates';
            app.MapCoordinatesDropDown = uidropdown(app.EBSDDataAnalysisPanel);
            app.MapCoordinatesDropDown.Items = {'y↑→x', 'y↓→x', 'x↑→y', 'x↓→y'};
            app.MapCoordinatesDropDown.Value = 'y↑→x';
            app.EulerCoordinatesDropDownLabel = uilabel(app.EBSDDataAnalysisPanel);
            app.EulerCoordinatesDropDownLabel.Text = 'Euler Coordinates';
            app.EulerCoordinatesDropDown = uidropdown(app.EBSDDataAnalysisPanel);
            app.EulerCoordinatesDropDown.Items = {'y↑→x', 'y↓→x', 'x↑→y', 'x↓→y', ''};
            app.EulerCoordinatesDropDown.Value = 'y↑→x';
            app.MapImage = uiimage(app.EBSDDataAnalysisPanel);
            app.EulerImage = uiimage(app.EBSDDataAnalysisPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end